classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Panel       matlab.ui.container.Panel
        TabGroup    matlab.ui.container.TabGroup
        Tab         matlab.ui.container.Tab
        Button      matlab.ui.control.Button
        Tab_2       matlab.ui.container.Tab
        Label_2     matlab.ui.control.Label
        Imgg        matlab.ui.control.Image
        Imgg2       matlab.ui.control.Image
        Tab_3       matlab.ui.container.Tab
        Label       matlab.ui.control.Label
        Imgg3       matlab.ui.control.Image
        Tab_4       matlab.ui.container.Tab
        Label_3     matlab.ui.control.Label
        TextArea_2  matlab.ui.control.TextArea
        TextArea    matlab.ui.control.TextArea
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Загрузка изображений
img1 = imread('ben1.jpg');
img2 = imread('ben4.jpg');
app.Imgg.ImageSource = img1;
app.Imgg2.ImageSource = img2;
app.Imgg.Visible = 'on';
app.Imgg2.Visible = 'on';
app.Imgg3.Visible = 'on';
app.TextArea.Visible = 'on';
app.TextArea_2.Visible = 'on';
app.Label.Visible = 'off';
app.Label_2.Visible = 'off';
app.Label_3.Visible = 'off';
 app.TabGroup.SelectedTab = app.Tab_2;
% Проверка наличия изображений
if isempty(img1) || isempty(img2)
    error('Ошибка загрузки изображений.');
end

% Преобразование изображений в оттенки серого
gray_img1 = rgb2gray(img1);
gray_img2 = rgb2gray(img2);

% Параметры для фильтрации Гаусса и порога Харриса
sigma = 2;     
thresh = 500; 

% Вычисление градиентов изображений
smoothed_img = imgaussfilt(double(gray_img1), sigma);
[Ix, Iy] = gradient(smoothed_img);
Ix2 = Ix .* Ix;
Iy2 = Iy .* Iy;
Ixy = Ix .* Iy;

% Применение фильтра Гаусса
gauss_filter = fspecial('gaussian', round(6*sigma), sigma);
Sx2 = imfilter(Ix2, gauss_filter, 'conv', 'replicate');
Sy2 = imfilter(Iy2, gauss_filter, 'conv', 'replicate');
Sxy = imfilter(Ixy, gauss_filter, 'conv', 'replicate');

% Вычисление определителя и следа матрицы Харриса
det_M = (Sx2 .* Sy2) - (Sxy .^ 2);
trace_M = Sx2 + Sy2;
harris_response = det_M - 0.04 * (trace_M .^ 2);

% Подавление немаксимумов
harris_response(harris_response < thresh) = 0;
corners = imregionalmax(harris_response);
[keypoints_y, keypoints_x] = find(corners);
keypoints = [keypoints_x, keypoints_y];

% Размер патча для описания ключевых точек
patch_size = 4;
matches = [];

% Поиск соответствий между ключевыми точками
for i = 1:size(keypoints, 1)
    kp1_x = keypoints(i, 1);
    kp1_y = keypoints(i, 2);
    if kp1_x < floor(patch_size / 2) || kp1_x > size(gray_img1, 2) - floor(patch_size / 2) || ...
            kp1_y < floor(patch_size / 2) || kp1_y > size(gray_img1, 1) - floor(patch_size / 2)
        continue; 
    end
    % Определение координаты верхнего левого угла патча
    patch_start_x = max(1, kp1_x - floor(patch_size / 2));
    patch_start_y = max(1, kp1_y - floor(patch_size / 2));
    % Определение координаты нижнего правого угла патча
    patch_end_x = min(size(gray_img1, 2), patch_start_x + patch_size - 1);
    patch_end_y = min(size(gray_img1, 1), patch_start_y + patch_size - 1);
    % Выделение патча изображения
    patch1 = gray_img1(patch_start_y:patch_end_y, patch_start_x:patch_end_x);
    
    best_match_idx = 0;
    best_match_dist = inf;
    for j = 1:size(keypoints, 1)
        kp2_x = keypoints(j, 1);
        kp2_y = keypoints(j, 2);
        if kp2_x < floor(patch_size / 2) || kp2_x > size(gray_img2, 2) - floor(patch_size / 2) || ...
                kp2_y < floor(patch_size / 2) || kp2_y > size(gray_img2, 1) - floor(patch_size / 2)
            continue; 
        end
        % Определение координаты верхнего левого угла патча
        patch_start_x = max(1, kp2_x - floor(patch_size / 2));
        patch_start_y = max(1, kp2_y - floor(patch_size / 2));
        % Определение координаты нижнего правого угла патча
        patch_end_x = min(size(gray_img2, 2), patch_start_x + patch_size - 1);
        patch_end_y = min(size(gray_img2, 1), patch_start_y + patch_size - 1);
        % Выделение патча изображения
        patch2 = gray_img2(patch_start_y:patch_end_y, patch_start_x:patch_end_x);
        
        dist = norm(double(patch1(:)) - double(patch2(:)));
        if dist < best_match_dist
            best_match_dist = dist;
            best_match_idx = j;
        end
    end
    matches = [matches; [i, best_match_idx]];
end

% Подготовка результатов
num_matches = size(matches, 1);
img2_resized = imresize(img2, [size(img1, 1), size(img1, 2)]);
merged_img = cat(2, img1, img2_resized);
keypoints_img2_translated = keypoints;
keypoints_img2_translated(:, 1) = keypoints_img2_translated(:, 1) + size(img1, 2);

% Отображение соответствий на изображениях
merged_img = cat(2, img1, img2_resized);
keypoints_img2_translated = keypoints;
keypoints_img2_translated(:, 1) = keypoints_img2_translated(:, 1) + size(img1, 2);

% Создание временного скрытого figure
h = figure('Visible', 'off');
imshow(merged_img);
hold on;
for i = 1:size(matches, 1)
    idx1 = matches(i, 1);
    idx2 = matches(i, 2);
    kp1 = keypoints(idx1, :);
    kp2 = keypoints_img2_translated(idx2, :);
    plot([kp1(1), kp2(1)], [kp1(2), kp2(2)], 'p', 'LineWidth', 1);
    plot([kp1(1), kp2(1)], [kp1(2), kp2(2)], 'y', 'LineWidth', 1);
end
hold off;

% Захват текущего изображения без отображения
frame = getframe(h);
imgg3_data = frame.cdata;
close(h); % Закрытие временного figure

% Показать результат в объекте imgg3
app.Imgg3.ImageSource = imgg3_data;

app.TextArea.Value = {'Количество совпадений:', num2str(num_matches)};

app.TextArea_2.Value = 'Координаты ключевых точек:';
for i = 1:size(keypoints, 1)
    app.TextArea_2.Value = [app.TextArea_2.Value; ['[x,y] = ', num2str(keypoints(i, :))]];
end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.TabGroup = uitabgroup(app.Panel);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Главная';
            app.Button = uibutton(app.Tab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Стартуем';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = 'Исходн. изобр.';
            app.Imgg2 = uiimage(app.Tab_2);
            app.Imgg2.Visible = 'off';
            app.Imgg = uiimage(app.Tab_2);
            app.Imgg.Visible = 'off';
            app.Label_2 = uilabel(app.Tab_2);
            app.Label_2.Text = 'Нажмите на кнопку';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = 'Сравн. изобр.';
            app.Imgg3 = uiimage(app.Tab_3);
            app.Imgg3.Visible = 'off';
            app.Label = uilabel(app.Tab_3);
            app.Label.Text = 'НАЖМИ НА КНОПКУ!!!';
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = 'Доп. инф.';
            app.TextArea = uitextarea(app.Tab_4);
            app.TextArea.Visible = 'off';
            app.TextArea_2 = uitextarea(app.Tab_4);
            app.TextArea_2.Visible = 'off';
            app.Label_3 = uilabel(app.Tab_4);
            app.Label_3.Text = {'Я ПРЕДУПРЕЖДАЮ ВАС'; 'НАЖМИТЕ НА КНОПКУ!!!'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end